classdef localisation_ListeningTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TrainingPanel                 matlab.ui.container.Panel
        NoButton_7                    matlab.ui.control.Button
        YesButton_7                   matlab.ui.control.Button
        PlayBButton_7                 matlab.ui.control.Button
        PlayAButton_7                 matlab.ui.control.Button
        PleaseenteryounameandthenclickEnterbuttonLabel_3  matlab.ui.control.Label
        StartButton_2                 matlab.ui.control.Button
        SetVolumePanel                matlab.ui.container.Panel
        TestSoundButton               matlab.ui.control.Button
        PleaseenteryounameandthenclickEnterbuttonLabel_2  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ParticipantPanel              matlab.ui.container.Panel
        AgeEditField                  matlab.ui.control.EditField
        AgeEditFieldLabel             matlab.ui.control.Label
        GenderEditField               matlab.ui.control.EditField
        GenderEditFieldLabel          matlab.ui.control.Label
        TheuniqueidentifierEditField  matlab.ui.control.EditField
        TheuniqueidentifierEditFieldLabel  matlab.ui.control.Label
        PleaseenteryounameandthenclickEnterbuttonLabel  matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
        BeginPanel                    matlab.ui.container.Panel
        TimerLabel                    matlab.ui.control.Label
        Tiptag3Label                  matlab.ui.control.Label
        PartEditField_2               matlab.ui.control.NumericEditField
        Tiptag2Label                  matlab.ui.control.Label
        Tiptag1Label                  matlab.ui.control.Label
        TimerEditField                matlab.ui.control.NumericEditField
        PartEditField                 matlab.ui.control.NumericEditField
        PartEditFieldLabel            matlab.ui.control.Label
        BeginButton                   matlab.ui.control.Button
        SoundSource1Panel             matlab.ui.container.Panel
        TrialEditField                matlab.ui.control.NumericEditField
        TrialEditFieldLabel_ERback    matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel  matlab.ui.control.Label
        NoButton                      matlab.ui.control.Button
        YesButton                     matlab.ui.control.Button
        PlayBButton                   matlab.ui.control.Button
        PlayAButton                   matlab.ui.control.Button
        SoundSource2Panel             matlab.ui.container.Panel
        TrialEditField_2              matlab.ui.control.NumericEditField
        TrialEditField_2Label         matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel_2  matlab.ui.control.Label
        NoButton_2                    matlab.ui.control.Button
        YesButton_2                   matlab.ui.control.Button
        PlayBButton_2                 matlab.ui.control.Button
        PlayAButton_2                 matlab.ui.control.Button
        SoundSource3Panel             matlab.ui.container.Panel
        TrialEditField_3              matlab.ui.control.NumericEditField
        TrialEditField_3Label         matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel_3  matlab.ui.control.Label
        NoButton_3                    matlab.ui.control.Button
        YesButton_3                   matlab.ui.control.Button
        PlayBButton_3                 matlab.ui.control.Button
        PlayAButton_3                 matlab.ui.control.Button
        SoundSource4Panel             matlab.ui.container.Panel
        TrialEditField_4              matlab.ui.control.NumericEditField
        TrialEditField_4Label         matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel_4  matlab.ui.control.Label
        NoButton_4                    matlab.ui.control.Button
        YesButton_4                   matlab.ui.control.Button
        PlayBButton_4                 matlab.ui.control.Button
        PlayAButton_4                 matlab.ui.control.Button
        SoundSource5Panel             matlab.ui.container.Panel
        TrialEditField_5              matlab.ui.control.NumericEditField
        TrialEditField_5Label         matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel_5  matlab.ui.control.Label
        NoButton_5                    matlab.ui.control.Button
        YesButton_5                   matlab.ui.control.Button
        PlayBButton_5                 matlab.ui.control.Button
        PlayAButton_5                 matlab.ui.control.Button
        SoundSource6Panel             matlab.ui.container.Panel
        TrialEditField_6              matlab.ui.control.NumericEditField
        TrialEditField_6Label         matlab.ui.control.Label
        AreaudiosamplesAandBthesameLabel_6  matlab.ui.control.Label
        NoButton_6                    matlab.ui.control.Button
        YesButton_6                   matlab.ui.control.Button
        PlayBButton_6                 matlab.ui.control.Button
        PlayAButton_6                 matlab.ui.control.Button
        EndPanel                      matlab.ui.container.Panel
        EndButton                     matlab.ui.control.Button
    end

        properties (Access = private)
            % TAKEN FROM HUAN MI'S CODE
            MemoryTimer        % Timer object
            Timernum = 0           % number object
        end
        methods (Access = private)
            
            % TAKEN FROM HUAN MI'S CODE
            function MemoryTimerFcn(app,~,~)
                  app.TimerEditField.Value = app.Timernum;        
                  app.Timernum = app.Timernum - 1;
                  app.TimerEditField.Value = app.Timernum;        
                  if (app.Timernum == 0)
                      % Stop the timer
                      stop(app.MemoryTimer);
                  end
            end
            
            % Function resets appropriate variables to their initial status
            function newStaircase(app)
                % Global variables necessary
                global currentRotation;
                global switches;
                global previousAnswer;
                global trial;
                global yesAnswers answersFollowing5thYes;
                global reversalValues;
                global trialSelector staircaseNumber;
                global leftRight soundSource;

                % Left right: 1 indicates left, -1 indicates right      
                % Sound source: 1 indicates noise, 2 indicates speech
                % Set up audio
                    %display(trialSelector(staircaseNumber));
                    if (trialSelector(staircaseNumber) == 1)
                        leftRight = -1;
                        soundSource = 1;
                        disp('Sound Source: Noise');
                        disp('Direction: Right');
                    elseif (trialSelector(staircaseNumber) == 2)
                        leftRight = 1;
                        soundSource = 1;
                        disp('Sound Source: Noise');
                        disp('Direction: Left');
                    elseif (trialSelector(staircaseNumber) == 3)
                        leftRight = -1;
                        soundSource = 1;
                        disp('Sound Source: Noise');
                        disp('Direction: Right (2)');
                    elseif (trialSelector(staircaseNumber) == 4)
                        leftRight = 1;
                        soundSource = 2;
                        disp('Sound Source: Speech');
                        disp('Direction: Left');
                    elseif (trialSelector(staircaseNumber) == 5)
                        leftRight = -1;
                        soundSource = 2;
                        disp('Sound Source: Speech');
                        disp('Direction: Right');
                    elseif (trialSelector(staircaseNumber) == 6)
                        leftRight = 1;
                        soundSource = 2;
                        disp('Sound Source: Speech');
                        disp('Direction: Left (2)');
                    end
                    
                % Set the sound source
                if (soundSource == 1)
                    randomNoise(app);
                else
                    randomSpeech(app);
                end

                % Initial rotation - 90 degrees
                currentRotation = leftRight*90;

                % Number of times the direction of the staircase has
                % switched - 0
                switches = 0;

                % The previous answer given - this method assumes the
                % first answer is 'no', hence using 'false' here will not
                % produce a 'switch'
                previousAnswer = false;

                % Number of times the participant has answered 'Yes' - 0
                yesAnswers = 0;             
                % Following the 5th 'Yes' answer, the number of trials that
                % have been completed - 0
                answersFollowing5thYes = 0;

                % Trial number - 1
                trial = 1;
    
                % Reversal values - blank array to hold answerss
                reversalValues = [];
            end
            % Function triggered following the press of either 'yes' or
            % 'no' - switches to the next audio sample
            function next(app)
                % Global variables necessary
                global switches currentRotation trial yesAnswers maxAngle answersFollowing5thYes reversalValues;
                global threshold1 threshold2 threshold3 threshold4 threshold5 threshold6;
                global trialSelector staircaseNumber;
                global leftRight soundSource;
                global u_reaper;
                global angleValues;
                
                % Store current angle
                angleValues(trialSelector(staircaseNumber), trial) = currentRotation;
                
                % Stop sound
                fopen(u_reaper);
                    oscsend(u_reaper, 't/stop','i', 1);
                fclose(u_reaper);

                % If the participant has given 5 or more 'Yes' responses,
                % increment the variable 'answersFollowing5thYes' - this
                % is used for one of the stop conditions (10 answers
                % following the 5th yes)
                if (yesAnswers >=5)
                        answersFollowing5thYes = answersFollowing5thYes + 1;
                end

                % Stop conditions:
                %   30 trials
                %   10 answers following the fifth yes
                % If the stop conditions are not met
                if (trial < 30 && answersFollowing5thYes < 10)
                    
                    % Increment the trial number
                    trial = trial + 1;
    
                    % Change the increment
                    if (yesAnswers < 3)
                        increment = 30;
                    elseif (yesAnswers < 5 && yesAnswers >= 3)
                        increment = 10;
                    else
                        increment = 2;
                    end
        
                    % Determine direction of staircase
                    %   1   - Increasing
                    %   -1  - Decreasing
                    if (rem(switches,2) ~= 0)
                        direction = 1;
                    else
                        direction = -1;
                    end

                    % Update the rotation value:
                    % If this will be in the range 0 - maxAngle, calculate 
                    % as normal
                    % If it will be outside of this range, set to the
                    % maximum or minimum value
                    if (leftRight == 1)
                        if (currentRotation + direction*increment*leftRight < 0)
                            currentRotation = 0;
                        elseif (currentRotation + direction*increment*leftRight > leftRight*maxAngle)
                            currentRotation = maxAngle;
                        else 
                            currentRotation = currentRotation + direction*increment*leftRight;
                        end
                    elseif (leftRight == -1)
                        if (currentRotation + direction*increment*leftRight > 0)
                            currentRotation = 0;
                        elseif (currentRotation + direction*increment*leftRight < leftRight*maxAngle)
                            currentRotation = - maxAngle;
                        else 
                            currentRotation = currentRotation + direction*increment*leftRight;
                        end
                    end

                    % Update trial label in app
                    if (staircaseNumber == 1)
                        app.TrialEditField.Value = trial;
                    elseif (staircaseNumber == 2)
                        app.TrialEditField_2.Value = trial;
                    elseif (staircaseNumber == 3)
                        app.TrialEditField_3.Value = trial;
                    elseif (staircaseNumber == 4)
                        app.TrialEditField_4.Value = trial;
                    elseif (staircaseNumber == 5)
                        app.TrialEditField_5.Value = trial;
                    elseif (staircaseNumber == 6)
                        app.TrialEditField_6.Value = trial;
                    end
                    
                    % Change the sound source to a random noise or speech
                    % extract
                    if (soundSource == 1)
                        randomNoise(app);
                    else
                        randomSpeech(app);
                    end

                % If the stop conditions are met
                else
                    % Calculate threshold - this is the average of the last
                    % 10 values
                    if (length(reversalValues) < 10 && isempty(reversalValues) ~= 1)
                        average = mean(reversalValues);
                    elseif (length(reversalValues) >= 10)
                        average = mean(reversalValues(end - 9: end));
                    else
                        average = 0;
                    end

                    if (trialSelector(staircaseNumber) == 1)
                        threshold1 = average;
                    elseif (trialSelector(staircaseNumber) == 2)
                        threshold2 = average;
                    elseif (trialSelector(staircaseNumber) == 3)
                        threshold3 = average;
                    elseif (trialSelector(staircaseNumber) == 4)
                        threshold4 = average;
                    elseif (trialSelector(staircaseNumber) == 5)
                        threshold5 = average;
                    elseif (trialSelector(staircaseNumber) == 6)
                        threshold6 = average;
                    end

                    % If we are not on the final staircase
                    if (staircaseNumber < 6)
                        if (staircaseNumber == 1)
                            app.SoundSource1Panel.Visible = 'off';
                        elseif (staircaseNumber == 2)
                            app.SoundSource2Panel.Visible = 'off';
                        elseif (staircaseNumber == 3)
                            app.SoundSource3Panel.Visible = 'off';
                        elseif (staircaseNumber == 4)
                            app.SoundSource4Panel.Visible = 'off';
                        elseif (staircaseNumber == 5)
                            app.SoundSource5Panel.Visible = 'off'; 
                        end
                        app.PartEditField_2.Value = staircaseNumber;
                        % Increment staircaseNumber
                        staircaseNumber = staircaseNumber + 1;
                        % Bring up the begin panel with timer
                        app.SetVolumePanel.Visible = 'off';
                        app.BeginPanel.Visible = 'on';
                        app.TimerEditField.Visible = 'on';
                        app.TimerLabel.Visible = 'on';
                        app.PartEditField.Visible = 'on';
                        app.PartEditField.Value = staircaseNumber;
                        app.PartEditField_2.Visible = 'on';
                        app.Tiptag1Label.Visible = 'on';
                        app.Tiptag2Label.Visible = 'on';
                        app.Tiptag3Label.Visible = 'on';
                    % If we are on the final staircase
                    else
                        app.SoundSource6Panel.Visible = 'off';
                        app.EndPanel.Visible = 'on';
                    end
                    
                    % Timer
                    if strcmp(app.MemoryTimer.Running, 'off')
                        % TODO - CHANGE BACK TO 30S
                           app.Timernum = 30;
                           start(app.MemoryTimer);
                    end
                end
            end
                    
            function randomNoise(app)
                global u_reaper;
                
                fopen(u_reaper);
                % Mute tracks 1 - 10
                muteTracks(app, 10);
                % Unmute random track between 1 and 5
                track = randi([1 5]);
                oscsend(u_reaper, ['t/track/', num2str(track), '/mute'],'i', 0);
                fclose(u_reaper);
            end
            
            function randomSpeech(app)
                global u_reaper;
                
                fopen(u_reaper);
                % Mute tracks 1 - 10
                muteTracks(app, 10);
                % Unmute random track between 6 and 10
                track = randi([6 10]);
                oscsend(u_reaper, ['t/track/', num2str(track), '/mute'],'i', 0);
                fclose(u_reaper);
            end
            
            function muteTracks(~, noTracks)
                global u_reaper
                % Mute first 'noTracks' tracks
                for i = 1: noTracks
                    oscsend(u_reaper, ['t/track/', num2str(i), '/mute'],'i', 1);
                end
            end
            
           function plotresults(app)
            
            global angleValues;
            global uniqueIdentifier;
            
            figure
            for i = 1: 6
                subplot(3, 2, i)
                plot(abs(angleValues(i, :)), '-x')
                
                if (i == 1 || i == 3)
                    title('Noise/ Sound Source Right');
                elseif (i == 2)
                    title('Noise/ Sound Source Left');
                elseif (i == 4 || i == 6)
                    title('Speech/ Sound Source Left');
                elseif (i == 5)
                    title('Speech/ Sound Source Right')
                end
                xlabel('Trial');
                ylabel('Angle')
                xlim([1 30]);
                ylim([0 90]);
                
            end
            
            saveas(gcf, ['Results/Individual Results/', uniqueIdentifier, '/', uniqueIdentifier, '.fig'])
           end
       
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Global variables necessary
            global trialSelector
            global u_reverbRotate u_reaper;
            global maxAngle;
            global resultsFileName;
            global staircaseNumber;
            global angleValues;
  
            trialSelector = randperm(6);
            
            % Hide all panels apart from the participant panel
            app.SetVolumePanel.Visible = 'off';
            app.TrainingPanel.Visible = 'off';
            app.BeginPanel.Visible = 'off';
            app.SoundSource1Panel.Visible = 'off';
            app.SoundSource2Panel.Visible = 'off';
            app.SoundSource3Panel.Visible = 'off';
            app.SoundSource4Panel.Visible = 'off';
            app.SoundSource5Panel.Visible = 'off';
            app.SoundSource6Panel.Visible = 'off';
            app.EndPanel.Visible = 'off';

            % Hide elements from the begin panel for its first uses
            app.TimerEditField.Visible = 'off';
            app.TimerLabel.Visible = 'off';
            app.PartEditField_2.Visible = 'off';
            app.Tiptag1Label.Visible = 'off';
            app.Tiptag2Label.Visible = 'off';
            app.Tiptag3Label.Visible = 'off';
           
            % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                        % Period is 1 second
                'TimerFcn', @app.MemoryTimerFcn);       % Specify callback function

            % Ports for OSC communication
            % MINERVA Laptop
            u_reverbRotate = udp("127.0.0.1", 6000);
            u_reaper = udp("127.0.0.1", 8000);

            % File name for resutls file
            resultsFileName = 'Results/results.xlsx';
            
            % Maximum angle possible
            maxAngle = 90;
            staircaseNumber = 1;
            
            % Set up angle values array
            angleValues = NaN(6, 30);

            % Zero parameters for initial staircase
            %newStaircase(app);
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            global resultsFileName uniqueIdentifier gender age threshold1 threshold2 threshold3 threshold4 threshold5 threshold6;
            global angleValues;
                
            app.EndPanel.Visible = 'off';

            % Save results
            results = {uniqueIdentifier, gender, age, threshold1, threshold2, threshold3, threshold4, threshold5, threshold6};
            [~, strings, ~] = xlsread(resultsFileName, 'Results');
            [rows, ~] = size(strings);
 
            writecell(results, resultsFileName, 'Sheet', 'Results', 'Range', strcat('A', string(rows + 1)));
            
            % Make folder for individual result
            mkdir('Results\Individual Results\', uniqueIdentifier);
            
            % File name for individual results
            individualResultsFileName = ['Results/Individual Results/', uniqueIdentifier, '/', uniqueIdentifier, '.xlsx'];
            writecell(num2cell(angleValues), individualResultsFileName);
            
            % Plot results
            plotresults(app);

            delete(app);
        end

        % Value changed function: TheuniqueidentifierEditField
        function TheuniqueidentifierEditFieldValueChanged(app, event)
            value = app.TheuniqueidentifierEditField.Value;
            app.TheuniqueidentifierEditField.Value = value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            global uniqueIdentifier gender age;
            if (isempty(app.TheuniqueidentifierEditField.Value))
                errordlg('Please input your unique identifier','Warning');
            else
                if (isempty(app.GenderEditField.Value))
                    errordlg('Please input your gender','Warning');
                else
                    if (isempty(app.AgeEditField.Value))
                        errordlg('Please input your age','Warning');
                    else
                        app.SetVolumePanel.Visible = 'on';
                        app.ParticipantPanel.Visible = 'off';
                        uniqueIdentifier = app.TheuniqueidentifierEditField.Value;
                        gender = app.GenderEditField.Value;
                        age = app.AgeEditField.Value;
                    end
                end 
            end
        end

        % Button pushed function: PlayAButton, PlayAButton_2, 
        % PlayAButton_3, PlayAButton_4, PlayAButton_5, 
        % PlayAButton_6, TestSoundButton
        function PlayAButtonPushed(app, event)
            global u_reverbRotate u_reaper;

            fopen(u_reaper);
            fopen(u_reverbRotate);

            % Pause
            oscsend(u_reaper, 't/stop','i', 1);
            % Reset rotation
            oscsend(u_reverbRotate, '/SceneRotator/yaw','f', 0);
            % Play from start
            oscsend(u_reaper, 't/play','i', 1);
            
            fclose(u_reaper);
            fclose(u_reverbRotate);
        end

        % Button pushed function: PlayBButton, PlayBButton_2, 
        % PlayBButton_3, PlayBButton_4, PlayBButton_5, PlayBButton_6
        function PlayBButtonPushed(app, event)
            global u_reverbRotate u_reaper currentRotation;

            fopen(u_reaper);
            fopen(u_reverbRotate);

            % Pause
            oscsend(u_reaper, 't/stop','i', 1);
            % Set rotation
            oscsend(u_reverbRotate, '/SceneRotator/yaw','f', currentRotation);
            % Play from start
            oscsend(u_reaper, 't/play','i', 1);
            
            fclose(u_reaper);
            fclose(u_reverbRotate);
        end

        % Button pushed function: YesButton, YesButton_2, 
        % YesButton_3, YesButton_4, YesButton_5, YesButton_6
        function YesButtonPushed(app, event)
            global switches previousAnswer currentRotation reversalValues yesAnswers;

            if (previousAnswer ~= true)
                switches = switches + 1;
                reversalValues(end + 1) = currentRotation;
            end

            next(app);

            previousAnswer = true;

            yesAnswers = yesAnswers + 1;
        end

        % Button pushed function: NoButton, NoButton_2, NoButton_3, 
        % NoButton_4, NoButton_5, NoButton_6
        function NoButtonPushed(app, event)
            global switches previousAnswer currentRotation reversalValues;

            if (previousAnswer ~= false)
                switches = switches + 1;
                reversalValues(end + 1) = currentRotation;
            end

            next(app);

            previousAnswer = false;
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            global staircaseNumber;
            global trial;
            global u_reaper;
            if (app.Timernum == 0)
                fopen(u_reaper);
                        oscsend(u_reaper, 't/track/3/mute','i', 1);
                    fclose(u_reaper);
                newStaircase(app);
                app.BeginPanel.Visible = 'off';
                if (staircaseNumber == 1)
                    app.SoundSource1Panel.Visible = 'on';
                    app.TrialEditField.Value = trial;
                elseif (staircaseNumber == 2)
                    app.SoundSource2Panel.Visible = 'on';
                    app.TrialEditField_2.Value = trial;
                elseif(staircaseNumber == 3)
                    app.SoundSource3Panel.Visible = 'on';
                    app.TrialEditField_3.Value = trial;
                elseif(staircaseNumber == 4)
                    app.SoundSource4Panel.Visible = 'on';
                    app.TrialEditField_4.Value = trial;
                elseif(staircaseNumber == 5)
                    app.SoundSource5Panel.Visible = 'on';
                    app.TrialEditField_5.Value = trial;
                else
                    app.SoundSource6Panel.Visible = 'on';
                    app.TrialEditField_6.Value = trial;
                end
            end
            
        end

        % Button pushed function: StartButton, StartButton_2
        function startButtonPushed(app, event)
            global u_reaper b_testValue;
            
            % Stop audio
            fopen(u_reaper);
            oscsend(u_reaper, 't/stop','i', 1);
            fclose(u_reaper);
            
            if (app.SetVolumePanel.Visible == 'on')
                app.SetVolumePanel.Visible = 'off';
                app.TrainingPanel.Visible = 'on';
                % Set b test angle
                b_testValue = randi([0 90]);
            elseif (app.TrainingPanel.Visible == 'on')
                app.TrainingPanel.Visible = 'off';
                app.BeginPanel.Visible = 'on';
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if (app.EndPanel.Visible == 'off')
                if (app.BeginPanel.Visible == 'off')
                    switch key
                        case 'numpad4'
                            if app.TrainingPanel.Visible == 'off'
                                PlayAButtonPushed(app);
                            else
                                PlayATest(app);
                            end
                        case 'numpad6'
                            if app.TrainingPanel.Visible == 'off'
                                PlayBButtonPushed(app);
                            else
                                PlayBTest(app);
                            end
                        case 'numpad8'
                            if app.TrainingPanel.Visible == 'off'
                                YesButtonPushed(app);
                            else
                                YesTest(app);
                            end
                        case 'numpad2'
                            if app.TrainingPanel.Visible == 'off'
                                NoButtonPushed(app);
                            else
                                NoTest(app);
                            end
                    end
                end
            end
            
        end

        % Button pushed function: PlayAButton_7
        function PlayATest(app, event)
            global u_reverbRotate u_reaper;

            fopen(u_reaper);
            fopen(u_reverbRotate);

            % Pause
            oscsend(u_reaper, 't/stop','i', 1);
            % Reset rotation
            oscsend(u_reverbRotate, '/SceneRotator/yaw','f', 0);
            % Play from start
            oscsend(u_reaper, 't/play','i', 1);
            
            fclose(u_reaper);
            fclose(u_reverbRotate);
        end

        % Button pushed function: PlayBButton_7
        function PlayBTest(app, event)
            global u_reverbRotate u_reaper b_testValue;

            fopen(u_reaper);
            fopen(u_reverbRotate);
            
            % Pause
            oscsend(u_reaper, 't/stop','i', 1);
            % Reset rotation
            oscsend(u_reverbRotate, '/SceneRotator/yaw','f', b_testValue);
            % Play from start
            oscsend(u_reaper, 't/play','i', 1);
            
            fclose(u_reaper);
            fclose(u_reverbRotate);
        end

        % Button pushed function: YesButton_7
        function YesTest(app, event)
            global u_reaper b_testValue;
            
            % Stop sound
            fopen(u_reaper);
                oscsend(u_reaper, 't/stop','i', 1);
            fclose(u_reaper);
            
            % Choose random noise extract
            randomNoise(app);
            
            % Change b test angle
            b_testValue = randi([0 90]);
        end

        % Button pushed function: NoButton_7
        function NoTest(app, event)
            global u_reaper b_testValue;
            
            % Stop sound
            fopen(u_reaper);
                oscsend(u_reaper, 't/stop','i', 1);
            fclose(u_reaper);
            
            % Choose random noise extract
            randomNoise(app);
            
            % Change b test angle
            b_testValue = randi([0 90]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.EndPanel = uipanel(app.UIFigure);
            app.EndPanel.Title = 'End';
            app.EndButton = uibutton(app.EndPanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.SoundSource6Panel = uipanel(app.UIFigure);
            app.SoundSource6Panel.Title = 'Sound Source 6';
            app.PlayAButton_6 = uibutton(app.SoundSource6Panel, 'push');
            app.PlayAButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton_6.Text = 'Play A';
            app.PlayBButton_6 = uibutton(app.SoundSource6Panel, 'push');
            app.PlayBButton_6.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton_6.Text = 'Play B';
            app.YesButton_6 = uibutton(app.SoundSource6Panel, 'push');
            app.YesButton_6.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_6.Text = 'Yes';
            app.NoButton_6 = uibutton(app.SoundSource6Panel, 'push');
            app.NoButton_6.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_6.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel_6 = uilabel(app.SoundSource6Panel);
            app.AreaudiosamplesAandBthesameLabel_6.Text = 'Are audio samples A and B the same?';
            app.TrialEditField_6Label = uilabel(app.SoundSource6Panel);
            app.TrialEditField_6Label.Text = 'Trial';
            app.TrialEditField_6 = uieditfield(app.SoundSource6Panel, 'numeric');
            app.SoundSource5Panel = uipanel(app.UIFigure);
            app.SoundSource5Panel.Title = 'Sound Source 5';
            app.PlayAButton_5 = uibutton(app.SoundSource5Panel, 'push');
            app.PlayAButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton_5.Text = 'Play A';
            app.PlayBButton_5 = uibutton(app.SoundSource5Panel, 'push');
            app.PlayBButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton_5.Text = 'Play B';
            app.YesButton_5 = uibutton(app.SoundSource5Panel, 'push');
            app.YesButton_5.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_5.Text = 'Yes';
            app.NoButton_5 = uibutton(app.SoundSource5Panel, 'push');
            app.NoButton_5.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_5.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel_5 = uilabel(app.SoundSource5Panel);
            app.AreaudiosamplesAandBthesameLabel_5.Text = 'Are audio samples A and B the same?';
            app.TrialEditField_5Label = uilabel(app.SoundSource5Panel);
            app.TrialEditField_5Label.Text = 'Trial';
            app.TrialEditField_5 = uieditfield(app.SoundSource5Panel, 'numeric');
            app.SoundSource4Panel = uipanel(app.UIFigure);
            app.SoundSource4Panel.Title = 'Sound Source 4';
            app.PlayAButton_4 = uibutton(app.SoundSource4Panel, 'push');
            app.PlayAButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton_4.Text = 'Play A';
            app.PlayBButton_4 = uibutton(app.SoundSource4Panel, 'push');
            app.PlayBButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton_4.Text = 'Play B';
            app.YesButton_4 = uibutton(app.SoundSource4Panel, 'push');
            app.YesButton_4.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_4.Text = 'Yes';
            app.NoButton_4 = uibutton(app.SoundSource4Panel, 'push');
            app.NoButton_4.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_4.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel_4 = uilabel(app.SoundSource4Panel);
            app.AreaudiosamplesAandBthesameLabel_4.Text = 'Are audio samples A and B the same?';
            app.TrialEditField_4Label = uilabel(app.SoundSource4Panel);
            app.TrialEditField_4Label.Text = 'Trial';
            app.TrialEditField_4 = uieditfield(app.SoundSource4Panel, 'numeric');
            app.SoundSource3Panel = uipanel(app.UIFigure);
            app.SoundSource3Panel.Title = 'Sound Source 3';
            app.PlayAButton_3 = uibutton(app.SoundSource3Panel, 'push');
            app.PlayAButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton_3.Text = 'Play A';
            app.PlayBButton_3 = uibutton(app.SoundSource3Panel, 'push');
            app.PlayBButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton_3.Text = 'Play B';
            app.YesButton_3 = uibutton(app.SoundSource3Panel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_3.Text = 'Yes';
            app.NoButton_3 = uibutton(app.SoundSource3Panel, 'push');
            app.NoButton_3.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_3.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel_3 = uilabel(app.SoundSource3Panel);
            app.AreaudiosamplesAandBthesameLabel_3.Text = 'Are audio samples A and B the same?';
            app.TrialEditField_3Label = uilabel(app.SoundSource3Panel);
            app.TrialEditField_3Label.Text = 'Trial';
            app.TrialEditField_3 = uieditfield(app.SoundSource3Panel, 'numeric');
            app.SoundSource2Panel = uipanel(app.UIFigure);
            app.SoundSource2Panel.Title = 'Sound Source 2';
            app.PlayAButton_2 = uibutton(app.SoundSource2Panel, 'push');
            app.PlayAButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton_2.Text = 'Play A';
            app.PlayBButton_2 = uibutton(app.SoundSource2Panel, 'push');
            app.PlayBButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton_2.Text = 'Play B';
            app.YesButton_2 = uibutton(app.SoundSource2Panel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton_2.Text = 'Yes';
            app.NoButton_2 = uibutton(app.SoundSource2Panel, 'push');
            app.NoButton_2.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton_2.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel_2 = uilabel(app.SoundSource2Panel);
            app.AreaudiosamplesAandBthesameLabel_2.Text = 'Are audio samples A and B the same?';
            app.TrialEditField_2Label = uilabel(app.SoundSource2Panel);
            app.TrialEditField_2Label.Text = 'Trial';
            app.TrialEditField_2 = uieditfield(app.SoundSource2Panel, 'numeric');
            app.SoundSource1Panel = uipanel(app.UIFigure);
            app.SoundSource1Panel.Title = 'Sound Source 1';
            app.PlayAButton = uibutton(app.SoundSource1Panel, 'push');
            app.PlayAButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.PlayAButton.Text = 'Play A';
            app.PlayBButton = uibutton(app.SoundSource1Panel, 'push');
            app.PlayBButton.ButtonPushedFcn = createCallbackFcn(app, @PlayBButtonPushed, true);
            app.PlayBButton.Text = 'Play B';
            app.YesButton = uibutton(app.SoundSource1Panel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.SoundSource1Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.AreaudiosamplesAandBthesameLabel = uilabel(app.SoundSource1Panel);
            app.AreaudiosamplesAandBthesameLabel.Text = 'Are audio samples A and B the same?';
            app.TrialEditFieldLabel_ERback = uilabel(app.SoundSource1Panel);
            app.TrialEditFieldLabel_ERback.Text = 'Trial';
            app.TrialEditField = uieditfield(app.SoundSource1Panel, 'numeric');
            app.BeginPanel = uipanel(app.UIFigure);
            app.BeginPanel.Title = 'Begin';
            app.BeginButton = uibutton(app.BeginPanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.PartEditFieldLabel = uilabel(app.BeginPanel);
            app.PartEditFieldLabel.Text = 'Part';
            app.PartEditField = uieditfield(app.BeginPanel, 'numeric');
            app.PartEditField.Value = 1;
            app.TimerEditField = uieditfield(app.BeginPanel, 'numeric');
            app.Tiptag1Label = uilabel(app.BeginPanel);
            app.Tiptag1Label.Text = 'When the timer displays 0, you can click Begin to continue the next test.';
            app.Tiptag2Label = uilabel(app.BeginPanel);
            app.Tiptag2Label.Text = 'At the end of Part ';
            app.PartEditField_2 = uieditfield(app.BeginPanel, 'numeric');
            app.PartEditField_2.Value = 1;
            app.Tiptag3Label = uilabel(app.BeginPanel);
            app.Tiptag3Label.Text = 'take a 30 second break before you start the next test. ';
            app.TimerLabel = uilabel(app.BeginPanel);
            app.TimerLabel.Text = 'Timer';
            app.ParticipantPanel = uipanel(app.UIFigure);
            app.ParticipantPanel.Title = 'Participant ';
            app.EnterButton = uibutton(app.ParticipantPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.PleaseenteryounameandthenclickEnterbuttonLabel = uilabel(app.ParticipantPanel);
            app.PleaseenteryounameandthenclickEnterbuttonLabel.Text = {'Please create a unique identifier.'; 'Input the last 3 digits of your phone number,'; 'and the last 3 digits or letters of your postcode,'; 'for example, 9304NJ.'; 'and then click ''Enter'' button.'; ''};
            app.TheuniqueidentifierEditFieldLabel = uilabel(app.ParticipantPanel);
            app.TheuniqueidentifierEditFieldLabel.Text = 'The unique identifier';
            app.TheuniqueidentifierEditField = uieditfield(app.ParticipantPanel, 'text');
            app.TheuniqueidentifierEditField.ValueChangedFcn = createCallbackFcn(app, @TheuniqueidentifierEditFieldValueChanged, true);
            app.GenderEditFieldLabel = uilabel(app.ParticipantPanel);
            app.GenderEditFieldLabel.Text = 'Gender';
            app.GenderEditField = uieditfield(app.ParticipantPanel, 'text');
            app.AgeEditFieldLabel = uilabel(app.ParticipantPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.ParticipantPanel, 'text');
            app.SetVolumePanel = uipanel(app.UIFigure);
            app.SetVolumePanel.Title = 'Set Volume';
            app.StartButton = uibutton(app.SetVolumePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PleaseenteryounameandthenclickEnterbuttonLabel_2 = uilabel(app.SetVolumePanel);
            app.PleaseenteryounameandthenclickEnterbuttonLabel_2.Text = {'Before you begin, please listen to the following audio sample '; 'and set the volume on the interface to a comfortable level. '; 'This must stay the same for the entirety of the test.'};
            app.TestSoundButton = uibutton(app.SetVolumePanel, 'push');
            app.TestSoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAButtonPushed, true);
            app.TestSoundButton.Text = 'Test Sound';
            app.TrainingPanel = uipanel(app.UIFigure);
            app.TrainingPanel.Title = 'Training';
            app.StartButton_2 = uibutton(app.TrainingPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.StartButton_2.Text = 'Start';
            app.PleaseenteryounameandthenclickEnterbuttonLabel_3 = uilabel(app.TrainingPanel);
            app.PleaseenteryounameandthenclickEnterbuttonLabel_3.Text = {'This is an example of the test interface. '; 'Please feel free to familiarise yourself with it.'};
            app.PlayAButton_7 = uibutton(app.TrainingPanel, 'push');
            app.PlayAButton_7.ButtonPushedFcn = createCallbackFcn(app, @PlayATest, true);
            app.PlayAButton_7.Text = 'Play A';
            app.PlayBButton_7 = uibutton(app.TrainingPanel, 'push');
            app.PlayBButton_7.ButtonPushedFcn = createCallbackFcn(app, @PlayBTest, true);
            app.PlayBButton_7.Text = 'Play B';
            app.YesButton_7 = uibutton(app.TrainingPanel, 'push');
            app.YesButton_7.ButtonPushedFcn = createCallbackFcn(app, @YesTest, true);
            app.YesButton_7.Text = 'Yes';
            app.NoButton_7 = uibutton(app.TrainingPanel, 'push');
            app.NoButton_7.ButtonPushedFcn = createCallbackFcn(app, @NoTest, true);
            app.NoButton_7.Text = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = localisation_ListeningTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end